classdef BEM_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UITextboxStimulusParameters  matlab.ui.control.TextArea
        UIDropdownStimulusType       matlab.ui.control.DropDown
        UILabelStimulus              matlab.ui.control.Label
        UIPlotStimulus               matlab.ui.control.UIAxes
        DisparitySliderLabel         matlab.ui.control.Label
        UISliderStimulusDisparity    matlab.ui.control.Slider
        AnaglyphSwitchLabel          matlab.ui.control.Label
        UISwitchStimulusAnaglyph     matlab.ui.control.Switch
        UIButtonStimulusGenerate     matlab.ui.control.Button
        UITextboxRfParameters        matlab.ui.control.TextArea
        UILabelRf                    matlab.ui.control.Label
        UIPlotRf                     matlab.ui.control.UIAxes
        UIButtonStimulusEye          matlab.ui.control.Button
        UILabelStimulusEye           matlab.ui.control.Label
        UIButtonRfEye                matlab.ui.control.Button
        UILabelStimulusEye_2         matlab.ui.control.Label
        UIButtonRfPhase              matlab.ui.control.Button
        PhaseLabel                   matlab.ui.control.Label
        UIButtonRunModel             matlab.ui.control.Button
        UILabelStimulus_2            matlab.ui.control.Label
        UIPlotTimecourse             matlab.ui.control.UIAxes
        UILabelRf_2                  matlab.ui.control.Label
        UILabelTuningCurve_2         matlab.ui.control.Label
        UILabelVersion               matlab.ui.control.Label
        ApertureLabel                matlab.ui.control.Label
        UISpinnerStimulusAperture    matlab.ui.control.Spinner
        UIPlotTuningCurve            matlab.ui.control.UIAxes
        TuningcurveLabel             matlab.ui.control.Label
        FrameSpinnerLabel_2          matlab.ui.control.Label
        UISpinnerStimulusFrame       matlab.ui.control.Spinner
        UIPanelDisplay               matlab.ui.container.Panel
        UIBoxOutputType              matlab.ui.control.CheckBox
        UIBoxAverageFrames           matlab.ui.control.CheckBox
        UIBoxAverageApertures        matlab.ui.control.CheckBox
        UIBoxAverageDisparities      matlab.ui.control.CheckBox
        UILabelRf_3                  matlab.ui.control.Label
        UISpinnerScale               matlab.ui.control.Spinner
        UILabelScale                 matlab.ui.control.Label
    end

    
    % Values to share across functions
    properties (Access = private)
        Parameters
        Stimulus
        CellPop
        Simple
        Complex
        Display
    end
    
    % Slave functions
    methods (Access = private)
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%
        %% Update stimulus plot %%
        %%%%%%%%%%%%%%%%%%%%%%%%%%
        function updatestimplot(app)
            
            % Indices
            D = app.Display.Disparity; % Index
            A = app.Display.Aperture; % Index
            F = app.Display.Frame; % Index
            E = app.Display.StimulusEye; % 1 = left, 2 = right
            
            % Pull images
            Im(:,:,1) = app.Stimulus.ImL(:, :, D, A, F);
            Im(:,:,2) = app.Stimulus.ImR(:, :, D, A, F);
            
            % Anaglyph options
            switch app.Display.StimulusAnaglyph
                case 1
                    % Display anaglyph
                    imshowpair(Im(:,:,1), Im(:,:,2), 'falsecolor', 'ColorChannels', [1 2 2], 'Parent', app.UIPlotStimulus);
                    
                case 0
                    % Display regular
                    imshow(Im(:,:,E), 'DisplayRange', [-1 1], 'Parent', app.UIPlotStimulus);
            end
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% Update receptive field plot %%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function updaterfplot(app)
            
            % Indices
            E = app.Display.RfEye; % Index
            P = app.Display.RfPhase; % Phase in radians
            
            % Generate images
            [Im(:,:,1), Im(:,:,2)] = BEM_filter(app.Parameters, app.CellPop, 1, P);
            
            % Display
            imshow(Im(:,:,E), 'DisplayRange', [-1 1], 'Parent', app.UIPlotRf);
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% Update tuning curve plot %%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function updatetuningcurveplot(app)
            
            % Indices
            A = app.Display.Aperture; % Index
            F = app.Display.Frame; % Index
            O = app.Display.OutputType; % String
            
            % Get data
            X = app.Parameters.Stim.Disparity;
            switch O
                case 'simple'
                    Y = app.Simple;
                case 'complex'
                    Y = app.Complex;
            end
            
            % Average across apertures
            if app.Display.AverageAperture
                Y = mean(Y, 3);
            else
                Y = Y(:, :, A, :, :);
            end
            
            % Average across frames
            if app.Display.AverageFrame
                E = std(Y, [], 4) ./ sqrt(size(Y, 4));
                Y = mean(Y, 4);
            else
                Y = Y(:, :, :, F, :);
                E = nan(size(Y));
            end
            
            % Reshape into;
            %  Simple  [disparity x phase]
            %  Complex [disparity x 1]
            Y = squeeze(Y);
            E = squeeze(E);
            
            % Plot
            switch O
                case 'simple'
                    
                    % Plot
                    h = plot(app.UIPlotTuningCurve, X, Y);
                    
                    % Tidy
                    h(1).Color = [1 0.2 0.2];
                    h(2).Color = [1 0.6 0.6];
                    h(3).Color = [0.2 0.2 1];
                    h(4).Color = [0.6 0.6 1];
                    h(1).LineStyle = '-';
                    h(2).LineStyle = ':';
                    h(3).LineStyle = '-';
                    h(4).LineStyle = ':';
                    h(1).LineWidth = 2;
                    h(2).LineWidth = 4;
                    h(3).LineWidth = 2;
                    h(4).LineWidth = 4;
                    
                case 'complex'
                    
                    % Plot
                    h = errorbar(app.UIPlotTuningCurve, X, Y, E);
                    
                    % Tidy
                    h.Color = [0.2 0.2 0.2];
                    h.LineStyle = '-';
                    h.LineWidth = 4;
            end
            
            % Scale axis
            app.UIPlotTuningCurve.YLim = [0, app.Display.Scale];
            app.UIPlotTuningCurve.YTick = [0, app.Display.Scale];
            app.UIPlotTuningCurve.XTick = round(linspace(min(X), max(X), 11), 2);
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% Update timecourse plot %%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function updatetimecourseplot(app)
            
            % Indices
            D = app.Display.Disparity; % Index
            F = app.Display.Frame; % Index
            O = app.Display.OutputType; % Index
            
            % Get data
            X = 1 : app.Parameters.Stim.ApSteps;
            switch O
                case 'simple'
                    Y = app.Simple;
                case 'complex'
                    Y = app.Complex;
            end
            
            % Average across disparities
            if app.Display.AverageDisparity
                Y = mean(Y, 2);
            else
                Y = Y(:, D, :, :, :);
            end
            
            % Average across frames
            if app.Display.AverageFrame
                E = std(Y, [], 4) ./ sqrt(size(Y, 4));
                Y = mean(Y, 4);
            else
                Y = Y(:, :, :, F, :);
                E = nan(size(Y));
            end
            
            % Reshape into;
            %  Simple  [aperture x phase]
            %  Complex [aperture x 1]
            Y = squeeze(Y);
            E = squeeze(E);

            % Plot
            switch O
                case 'simple'
                    
                    % Plot
                    h = plot(app.UIPlotTimecourse, X, Y);
                    
                    % Tidy
                    h(1).Color = [1 0.2 0.2];
                    h(2).Color = [1 0.6 0.6];
                    h(3).Color = [0.2 0.2 1];
                    h(4).Color = [0.6 0.6 1];
                    h(1).LineStyle = '-';
                    h(2).LineStyle = ':';
                    h(3).LineStyle = '-';
                    h(4).LineStyle = ':';
                    h(1).LineWidth = 2;
                    h(2).LineWidth = 4;
                    h(3).LineWidth = 2;
                    h(4).LineWidth = 4;
                    
                case 'complex'
                    
                    % Plot
                    h = errorbar(app.UIPlotTimecourse, X, Y, E);
                    
                    % Tidy
                    h.Color = [0.2 0.2 0.2];
                    h.LineStyle = '-';
                    h.LineWidth = 4;
            end
            
            % Scale axis
            app.UIPlotTimecourse.YLim = [0, app.Display.Scale];
            app.UIPlotTimecourse.YTick = [0, app.Display.Scale];
            app.UIPlotTimecourse.XLim = [0, app.Parameters.Stim.ApSteps + 1];
            app.UIPlotTimecourse.XTick = 0 : ceil(log10(app.Parameters.Stim.ApSteps)) : app.Parameters.Stim.ApSteps;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Get default parameters
            app.Parameters = BEM_parameters;
            
            % Override default settings
            app.Parameters.NumCells = 1;
            app.Parameters.ParallelPool = 0;
            
            % Hard defaults
            app.Display.Disparity = round(length(app.Parameters.Stim.Disparity) / 2);
            app.Display.Aperture = 1;
            app.Display.Frame = 1;
            app.Display.StimulusEye = 1;
            app.Display.StimulusAnaglyph = 0;
            app.Display.RfEye = 1;
            app.Display.RfPhase = 0;
            app.Display.OutputType = 'simple';
            app.Display.AverageAperture = 0;
            app.Display.AverageFrame = 0;
            app.Display.AverageDisparity = 0;
            app.Display.Scale = 10 .^ 6;
            
            % Inherit values from GUI
            ChangeStimulusType(app);
            ChangeStimulusParameters(app);
            ChangeRfParameters(app);
            
            % Generate default stimulus
            GenerateStimulus(app);
            
            % Run model
            RunModel(app);
            
        end

        % Value changed function: UIDropdownStimulusType
        function ChangeStimulusType(app, event)
            
            % Pull
            app.Parameters.Stim.Type = lower(app.UIDropdownStimulusType.Value);
            
        end

        % Value changed function: UITextboxStimulusParameters
        function ChangeStimulusParameters(app, event)
            
            % Parse stimulus parameters as variables. Expecting the following:
            % Nframes
            % Disparity
            % GratingSF
            % GratingOri
            % GratingPhase
            % RandotDotRadius
            % RandotDotNum
            % Aperture
            % ApertureBarWidth
            % ApertureBarOri
            for i = 1:length(app.UITextboxStimulusParameters.Value)
                eval(['app.Parameters.Stim.' app.UITextboxStimulusParameters.Value{i}]);
            end
            
            % Change disparity slider
            app.UISliderStimulusDisparity.Limits = [min(app.Parameters.Stim.Disparity), max(app.Parameters.Stim.Disparity)];
            app.UISliderStimulusDisparity.MajorTicks = linspace(min(app.Parameters.Stim.Disparity), max(app.Parameters.Stim.Disparity), 5);
            
            % Change apertures spinner
            if app.Parameters.Stim.ApSteps == 1
                app.UISpinnerStimulusAperture.Limits = [0, 1];
            else
                app.UISpinnerStimulusAperture.Limits = [1, app.Parameters.Stim.ApSteps];
            end
            
            % Change frames spinner
            if app.Parameters.Stim.Nframes == 1
                app.UISpinnerStimulusFrame.Limits = [0, 1];
            else
                app.UISpinnerStimulusFrame.Limits = [1, app.Parameters.Stim.Nframes];
            end
            
        end

        % Value changed function: UITextboxRfParameters
        function ChangeRfParameters(app, event)
            
            % Parse RF parameters as variables. Expecting the following:
            % X
            % Y
            % SF
            % Sigma1
            % Sigma2
            % TuningPos
            % TuningPha
            % Ori
            for i = 1:length(app.UITextboxRfParameters.Value)
                eval(['app.CellPop.' app.UITextboxRfParameters.Value{i}]);
            end
            
            % Shift XY position to be centred at 0,0
            app.CellPop.X = app.CellPop.X + (app.Parameters.ImSize / 2);
            app.CellPop.Y = app.CellPop.Y + (app.Parameters.ImSize / 2);
            
            % Convert TuningPha from radians to degVA
            app.CellPop.TuningPha = (app.CellPop.TuningPha * (1 / app.CellPop.SF)) / (2 * pi);
            
            % Update RF plot
            updaterfplot(app);
            
        end

        % Value changed function: UIBoxOutputType
        function ChangeOutputType(app, event)
            
            % Update display value based on current value
            switch app.UIBoxOutputType.Value
                case 0
                    app.Display.OutputType = 'simple';
                case 1
                    app.Display.OutputType = 'complex';
            end
            
            % Update output plots
            updatetuningcurveplot(app);
            updatetimecourseplot(app);
            
        end

        % Button pushed function: UIButtonStimulusGenerate
        function GenerateStimulus(app, event)
            
            % Make the stimulus
            app.Stimulus = BEM_make_stimulus(app.Parameters);
            
            % Update stimulus plot
            updatestimplot(app);
            
        end

        % Button pushed function: UIButtonRunModel
        function RunModel(app, event)
            
            % Run model
            [app.Simple, app.Complex] = BEM_run(app.Parameters, app.Stimulus, app.CellPop);
            
            % Update output plots
            updatetuningcurveplot(app);
            updatetimecourseplot(app);
            
        end

        % Value changed function: UISliderStimulusDisparity
        function ChangeDisplayDisparity(app, event)
            
            % Pull value from GUI
            [~, app.Display.Disparity] = min(abs(app.Parameters.Stim.Disparity - app.UISliderStimulusDisparity.Value));
            
            % Update stimulus and timecourse plots
            updatestimplot(app);
            updatetimecourseplot(app);
            
        end

        % Button pushed function: UIButtonStimulusEye
        function ChangeDisplayStimulusEye(app, event)
            
            % Update display value based on current value
            switch app.UIButtonStimulusEye.Text
                case 'Left'
                    app.UIButtonStimulusEye.Text = 'Right';
                    app.Display.StimulusEye = 2; % Index
                case 'Right'
                    app.UIButtonStimulusEye.Text = 'Left';
                    app.Display.StimulusEye = 1; % Index
            end
            
            % Update stimulus plot
            updatestimplot(app);
            
        end

        % Button pushed function: UIButtonRfEye
        function ChangeDisplayRfEye(app, event)
            
            % Update display value based on current value
            switch app.UIButtonRfEye.Text
                case 'Left'
                    app.UIButtonRfEye.Text = 'Right';
                    app.Display.RfEye = 2; % Index
                case 'Right'
                    app.UIButtonRfEye.Text = 'Left';
                    app.Display.RfEye = 1; % Index
            end
            
            % Update RF plot
            updaterfplot(app);
            
        end

        % Button pushed function: UIButtonRfPhase
        function ChangeDisplayRfPhase(app, event)
            
            % Update display value based on current value
            switch app.UIButtonRfPhase.Text
                case '0'
                    app.UIButtonRfPhase.Text = 'π';
                    app.Display.RfPhase = pi;
                case '0.5 π'
                    app.UIButtonRfPhase.Text = '1.5 π';
                    app.Display.RfPhase = 1.5 * pi;
                case 'π'
                    app.UIButtonRfPhase.Text = '0.5 π';
                    app.Display.RfPhase = 0.5 * pi;
                case '1.5 π'
                    app.UIButtonRfPhase.Text = '0';
                    app.Display.RfPhase = 0;
            end
            
            % Update RF plot
            updaterfplot(app);
            
        end

        % Value changed function: UISwitchStimulusAnaglyph
        function ChangeDisplayStimulusAnaglyph(app, event)
            
            % Update display value
            switch lower(app.UISwitchStimulusAnaglyph.Value)
                case 'on'
                    app.Display.StimulusAnaglyph = 1;
                case 'off'
                    app.Display.StimulusAnaglyph = 0;
            end
            
            % Update plot
            updatestimplot(app);
            
        end

        % Value changed function: UISpinnerStimulusFrame
        function ChangeDisplayFrame(app, event)
            
            % Pull value from GUI
            app.Display.Frame = round(app.UISpinnerStimulusFrame.Value);
            
            % Update stimulus and output plots
            updatestimplot(app);
            updatetuningcurveplot(app);
            updatetimecourseplot(app);
            
        end

        % Value changed function: UIBoxAverageFrames
        function ChangeDisplayAverageFrame(app, event)
            
            % Pull value from GUI
            app.Display.AverageFrame = app.UIBoxAverageFrames.Value;
            
            % Update output plots
            updatetuningcurveplot(app);
            updatetimecourseplot(app);
            
        end

        % Value changed function: UIBoxAverageApertures
        function ChangeDisplayAverageAperture(app, event)
            
            % Pull value from GUI
            app.Display.AverageAperture = app.UIBoxAverageApertures.Value;
            
            % Update output plots
            updatetuningcurveplot(app);
            updatetimecourseplot(app);
            
        end

        % Value changed function: UIBoxAverageDisparities
        function ChangeDisplayAverageDisparities(app, event)
            
            % Pull value from GUI
            app.Display.AverageDisparity = app.UIBoxAverageDisparities.Value;
            
            % Update output plots
            updatetuningcurveplot(app);
            updatetimecourseplot(app);
            
        end

        % Value changed function: UISpinnerStimulusAperture
        function ChangeDisplayAperture(app, event)
            
            % Pull value from GUI
            app.Display.Aperture = round(app.UISpinnerStimulusAperture.Value);
            
            % Update stimulus and tuning curve plots
            updatestimplot(app);
            updatetuningcurveplot(app);
            
        end

        % Value changed function: UISpinnerScale
        function ChangeDisplayScale(app, event)
            
            % Pull value from GUI
            app.Display.Scale = 10 .^ app.UISpinnerScale.Value;
            
            % Update output plots
            updatetuningcurveplot(app);
            updatetimecourseplot(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UITextboxStimulusParameters = uitextarea(app.UIFigure);
            app.UITextboxStimulusParameters.ValueChangedFcn = createCallbackFcn(app, @ChangeStimulusParameters, true);
            app.UITextboxStimulusParameters.Value = {'Disparity = -1:0.1:1; %deg'; 'GratingSF = 0.5; %cpd'; 'GratingOri = 0; %rad'; 'GratingPhase = 0; %rad'; 'NoiseBlockSize = 1; % pix'; 'RandotDotRadius = 0.2; %deg'; 'RandotDotNum = 200;'; 'Aperture = ''full'';'; 'ApSteps = 1;'; 'Nframes = 1;'; 'ApertureBarWidth = 1; %deg'; 'ApertureBarOri = ''lr'';'; 'Monocular = false;'};
            app.UIDropdownStimulusType = uidropdown(app.UIFigure);
            app.UIDropdownStimulusType.Items = {'Grating', 'Noise', 'Randot'};
            app.UIDropdownStimulusType.ValueChangedFcn = createCallbackFcn(app, @ChangeStimulusType, true);
            app.UIDropdownStimulusType.Value = 'Grating';
            app.UILabelStimulus = uilabel(app.UIFigure);
            app.UILabelStimulus.Text = 'Stimulus';
            app.UIPlotStimulus = uiaxes(app.UIFigure);
            title(app.UIPlotStimulus, '')
            xlabel(app.UIPlotStimulus, '')
            ylabel(app.UIPlotStimulus, '')
            app.UIPlotStimulus.XTick = [];
            app.UIPlotStimulus.YTick = [];
            app.DisparitySliderLabel = uilabel(app.UIFigure);
            app.DisparitySliderLabel.Text = 'Disparity';
            app.UISliderStimulusDisparity = uislider(app.UIFigure);
            app.UISliderStimulusDisparity.Limits = [-1 1];
            app.UISliderStimulusDisparity.MajorTicks = [-1 -0.5 0 0.5 1];
            app.UISliderStimulusDisparity.ValueChangedFcn = createCallbackFcn(app, @ChangeDisplayDisparity, true);
            app.AnaglyphSwitchLabel = uilabel(app.UIFigure);
            app.AnaglyphSwitchLabel.Text = 'Anaglyph';
            app.UISwitchStimulusAnaglyph = uiswitch(app.UIFigure, 'slider');
            app.UISwitchStimulusAnaglyph.ValueChangedFcn = createCallbackFcn(app, @ChangeDisplayStimulusAnaglyph, true);
            app.UIButtonStimulusGenerate = uibutton(app.UIFigure, 'push');
            app.UIButtonStimulusGenerate.ButtonPushedFcn = createCallbackFcn(app, @GenerateStimulus, true);
            app.UIButtonStimulusGenerate.Text = 'Generate stimulus';
            app.UITextboxRfParameters = uitextarea(app.UIFigure);
            app.UITextboxRfParameters.ValueChangedFcn = createCallbackFcn(app, @ChangeRfParameters, true);
            app.UITextboxRfParameters.Value = {'X = 0; % deg'; 'Y = 0; % deg'; 'SF = 0.5; % cpd'; 'Sigma1 = 1; % deg'; 'Sigma2 = 0.5; % deg'; 'TuningPos = 0; % deg'; 'TuningPha = 0; % rad'; 'Ori = 0; % rad'};
            app.UILabelRf = uilabel(app.UIFigure);
            app.UILabelRf.Text = 'Receptive field';
            app.UIPlotRf = uiaxes(app.UIFigure);
            title(app.UIPlotRf, '')
            xlabel(app.UIPlotRf, '')
            ylabel(app.UIPlotRf, '')
            app.UIPlotRf.XTick = [];
            app.UIPlotRf.YTick = [];
            app.UIButtonStimulusEye = uibutton(app.UIFigure, 'push');
            app.UIButtonStimulusEye.ButtonPushedFcn = createCallbackFcn(app, @ChangeDisplayStimulusEye, true);
            app.UIButtonStimulusEye.Text = 'Left';
            app.UILabelStimulusEye = uilabel(app.UIFigure);
            app.UILabelStimulusEye.Text = 'Eye';
            app.UIButtonRfEye = uibutton(app.UIFigure, 'push');
            app.UIButtonRfEye.ButtonPushedFcn = createCallbackFcn(app, @ChangeDisplayRfEye, true);
            app.UIButtonRfEye.Text = 'Left';
            app.UILabelStimulusEye_2 = uilabel(app.UIFigure);
            app.UILabelStimulusEye_2.Text = 'Eye';
            app.UIButtonRfPhase = uibutton(app.UIFigure, 'push');
            app.UIButtonRfPhase.ButtonPushedFcn = createCallbackFcn(app, @ChangeDisplayRfPhase, true);
            app.UIButtonRfPhase.Text = '0';
            app.PhaseLabel = uilabel(app.UIFigure);
            app.PhaseLabel.Text = 'Phase';
            app.UIButtonRunModel = uibutton(app.UIFigure, 'push');
            app.UIButtonRunModel.ButtonPushedFcn = createCallbackFcn(app, @RunModel, true);
            app.UIButtonRunModel.Text = 'Run model';
            app.UILabelStimulus_2 = uilabel(app.UIFigure);
            app.UILabelStimulus_2.Text = 'Stimulus';
            app.UIPlotTimecourse = uiaxes(app.UIFigure);
            title(app.UIPlotTimecourse, '')
            xlabel(app.UIPlotTimecourse, 'Aperture steps')
            ylabel(app.UIPlotTimecourse, 'Response')
            app.UIPlotTimecourse.XTick = [0 1];
            app.UIPlotTimecourse.YTick = [0 1];
            app.UILabelRf_2 = uilabel(app.UIFigure);
            app.UILabelRf_2.Text = 'Receptive field';
            app.UILabelTuningCurve_2 = uilabel(app.UIFigure);
            app.UILabelTuningCurve_2.Text = 'Timecourse';
            app.UILabelVersion = uilabel(app.UIFigure);
            app.UILabelVersion.Text = 'BEM Simulator';
            app.ApertureLabel = uilabel(app.UIFigure);
            app.ApertureLabel.Text = 'Aperture';
            app.UISpinnerStimulusAperture = uispinner(app.UIFigure);
            app.UISpinnerStimulusAperture.Limits = [1 5];
            app.UISpinnerStimulusAperture.RoundFractionalValues = 'on';
            app.UISpinnerStimulusAperture.ValueDisplayFormat = '%11g';
            app.UISpinnerStimulusAperture.ValueChangedFcn = createCallbackFcn(app, @ChangeDisplayAperture, true);
            app.UISpinnerStimulusAperture.Value = 1;
            app.UIPlotTuningCurve = uiaxes(app.UIFigure);
            title(app.UIPlotTuningCurve, '')
            xlabel(app.UIPlotTuningCurve, 'Disparity')
            ylabel(app.UIPlotTuningCurve, 'Response')
            app.UIPlotTuningCurve.XTick = [0 1];
            app.UIPlotTuningCurve.YTick = [0 1];
            app.TuningcurveLabel = uilabel(app.UIFigure);
            app.TuningcurveLabel.Text = 'Tuning curve';
            app.FrameSpinnerLabel_2 = uilabel(app.UIFigure);
            app.FrameSpinnerLabel_2.Text = 'Frame';
            app.UISpinnerStimulusFrame = uispinner(app.UIFigure);
            app.UISpinnerStimulusFrame.Limits = [1 5];
            app.UISpinnerStimulusFrame.RoundFractionalValues = 'on';
            app.UISpinnerStimulusFrame.ValueDisplayFormat = '%11g';
            app.UISpinnerStimulusFrame.ValueChangedFcn = createCallbackFcn(app, @ChangeDisplayFrame, true);
            app.UISpinnerStimulusFrame.Value = 1;
            app.UIPanelDisplay = uipanel(app.UIFigure);
            app.UIPanelDisplay.Title = 'Display';
            app.UIBoxOutputType = uicheckbox(app.UIPanelDisplay);
            app.UIBoxOutputType.ValueChangedFcn = createCallbackFcn(app, @ChangeOutputType, true);
            app.UIBoxOutputType.Text = '  Show complex cell';
            app.UIBoxAverageFrames = uicheckbox(app.UIPanelDisplay);
            app.UIBoxAverageFrames.ValueChangedFcn = createCallbackFcn(app, @ChangeDisplayAverageFrame, true);
            app.UIBoxAverageFrames.Text = '  Average frames';
            app.UIBoxAverageApertures = uicheckbox(app.UIPanelDisplay);
            app.UIBoxAverageApertures.ValueChangedFcn = createCallbackFcn(app, @ChangeDisplayAverageAperture, true);
            app.UIBoxAverageApertures.Text = '  Average apertures';
            app.UIBoxAverageDisparities = uicheckbox(app.UIPanelDisplay);
            app.UIBoxAverageDisparities.ValueChangedFcn = createCallbackFcn(app, @ChangeDisplayAverageDisparities, true);
            app.UIBoxAverageDisparities.Text = '  Average disparities';
            app.UILabelRf_3 = uilabel(app.UIFigure);
            app.UILabelRf_3.Text = 'Receptive field';
            app.UISpinnerScale = uispinner(app.UIFigure);
            app.UISpinnerScale.ValueChangedFcn = createCallbackFcn(app, @ChangeDisplayScale, true);
            app.UISpinnerScale.Value = 6;
            app.UILabelScale = uilabel(app.UIFigure);
            app.UILabelScale.Text = 'Scale';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BEM_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end