classdef BEM_plot_prffit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BEMpRFfitUIFigure  matlab.ui.Figure
        UIPlotFit          matlab.ui.control.UIAxes
        UIPlotRf           matlab.ui.control.UIAxes
        EntrySpinnerLabel  matlab.ui.control.Label
        UISpinnerEntry     matlab.ui.control.Spinner
        LabelText          matlab.ui.control.Label
        PlotscaleLabel     matlab.ui.control.Label
        UISpinnerScale     matlab.ui.control.Spinner
    end

    
    % Values to share across functions
    properties (Access = private)
        Display
        Parameters
        ModelFit
    end
    
    % Custom functions
    methods (Access = private)
        
        % Generic plot updating
        function updateplot(app)
            updatefitplot(app);
            updaterfplot(app);
            updatetext(app);
        end
        
        % Update pRF fit plot
        function updatefitplot(app)
            
            % Select data
            X = 1 : app.Parameters.Stim.ApSteps;
            Y(:,1) = app.ModelFit(app.Display.Entry).TsObserved;
            Y(:,2) = app.ModelFit(app.Display.Entry).TsFit;
            
            % Plot
            h = plot(app.UIPlotFit, X, Y);
            
            % Tidy plot
            h(1).LineWidth = 2;
            h(2).LineWidth = 2;
            h(1).Color = [0.2, 0.2, 1];
            h(2).Color = [1, 0.2, 0.2];
            h(1).LineStyle = '-';
            h(2).LineStyle = ':';
            legend(app.UIPlotFit, {'Signal', 'pRF fit'});
            
            % Tidy axes
            app.UIPlotFit.YLim(2) = app.Display.Scale;
        end
        
        % Update RF plot
        function updaterfplot(app)
            
            % Parameters
            Pfit = app.ModelFit(app.Display.Entry).Param(1:3); % X, Y, Sigma
            
            % Convert units from degrees to pixels
            ImSize = app.Parameters.ImSize * app.Parameters.PixPerDeg;
            Pfit = Pfit * app.Parameters.PixPerDeg;
            
            % Generate Gaussian image
            Im = BEM_gaussian(ImSize, [Pfit(1), Pfit(2)], [Pfit(3), Pfit(3)], 0);
            
            % Show
            imshow(Im, 'DisplayRange', [-1, 1], 'Parent', app.UIPlotRf);
        end
        
        % Update text
        function updatetext(app)
            
            % Extract model fit for easier indexing
            M = app.ModelFit(app.Display.Entry);
            
            % Create string
            TextString = {};
            TextString{end+1} = ['Entry #' num2str(app.Display.Entry)];
            TextString{end+1} = '';
            TextString{end+1} = ['Cell = ' num2str(M.Cell)];
            TextString{end+1} = ['Disparity = ' M.Disparity];
            TextString{end+1} = ['Frame = ' M.Frame];
            TextString{end+1} = ['X = ' num2str(M.Param(1), '%0.2f') '°'];
            TextString{end+1} = ['Y = ' num2str(M.Param(2), '%0.2f') '°'];
            TextString{end+1} = ['Sigma = ' num2str(M.Param(3), '%0.2f') '°'];
            TextString{end+1} = ['Amplitude = ' num2str(M.Param(4), '%0.2f')];
            TextString{end+1} = ['SSE = ' num2str(M.SSE, '%0.0f')];
            TextString{end+1} = ['R2 = ' num2str(M.R2, '%0.3f')];
            
            % Display
            app.LabelText.Text = TextString;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Parameters, ModelFit)
            
            % Help message
            if nargin < 2
                error('Usage:: BEM_plot_prffit(Parameters, ModelFit)')
            end
            
            % Parse inputs
            app.Parameters = Parameters;
            app.ModelFit = ModelFit;
            
            % Default start pulled from GUI
            app.Display.Entry = app.UISpinnerEntry.Value;
            app.Display.Scale = 10 .^ app.UISpinnerScale.Value;
            
            % Limit counter
            app.UISpinnerEntry.Limits = [0, length(ModelFit)];
            
            % Limit plot area, X-axis
            app.UIPlotFit.XLim = [0, Parameters.Stim.ApSteps + 1];
            
            % Limit bottom of plot area, Y-axis
            for i = 1 : length(ModelFit)
                M(i) = min([ModelFit(i).TsObserved; ModelFit(i).TsFit]);
            end
            app.UIPlotFit.YLim = [min(M), app.Display.Scale];
            
            % Update plots
            updateplot(app);
            
        end

        % Value changed function: UISpinnerEntry
        function ChangeEntry(app, event)
            
            % Pull value from GUI
            app.Display.Entry = app.UISpinnerEntry.Value;
            
            % Special case, don't allow zero
            if app.Display.Entry == 0
                app.UISpinnerEntry.Value = 1;
                app.Display.Entry = 1;
            end
            
            % Update plot
            updateplot(app);
            
        end

        % Value changed function: UISpinnerScale
        function ChangeScale(app, event)
            app.Display.Scale = 10 .^ app.UISpinnerScale.Value;
            updateplot(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BEMpRFfitUIFigure = uifigure;
            app.BEMpRFfitUIFigure.Name = 'BEM pRF fit';
            app.UIPlotFit = uiaxes(app.BEMpRFfitUIFigure);
            title(app.UIPlotFit, 'pRF model fit')
            xlabel(app.UIPlotFit, 'Aperture step')
            ylabel(app.UIPlotFit, 'Response')
            app.UIPlotRf = uiaxes(app.BEMpRFfitUIFigure);
            title(app.UIPlotRf, 'Population receptive field')
            xlabel(app.UIPlotRf, '')
            ylabel(app.UIPlotRf, '')
            app.UIPlotRf.XTick = [];
            app.UIPlotRf.YTick = [];
            app.EntrySpinnerLabel = uilabel(app.BEMpRFfitUIFigure);
            app.EntrySpinnerLabel.Text = 'Entry';
            app.UISpinnerEntry = uispinner(app.BEMpRFfitUIFigure);
            app.UISpinnerEntry.ValueChangedFcn = createCallbackFcn(app, @ChangeEntry, true);
            app.UISpinnerEntry.Value = 1;
            app.LabelText = uilabel(app.BEMpRFfitUIFigure);
            app.LabelText.Text = {'Entry #1'; ''; 'Cell = 1'; 'Disparity = ''average'''; 'Frame = ''average'''; 'X = 1.00 deg'; 'Y = 1.00 deg'; 'Sigma = 1.00 deg'; 'Amplitude = 10000'; 'SSE = 12345432'; 'R^2 = 0.95'};
            app.PlotscaleLabel = uilabel(app.BEMpRFfitUIFigure);
            app.PlotscaleLabel.Text = 'Plot scale';
            app.UISpinnerScale = uispinner(app.BEMpRFfitUIFigure);
            app.UISpinnerScale.ValueChangedFcn = createCallbackFcn(app, @ChangeScale, true);
            app.UISpinnerScale.Value = 6;
        end
    end

    methods (Access = public)

        % Construct app
        function app = BEM_plot_prffit(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BEMpRFfitUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BEMpRFfitUIFigure)
        end
    end
end