classdef BEM_plot_receptivefield < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BEMreceptivefieldUIFigure  matlab.ui.Figure
        UIPlot                     matlab.ui.control.UIAxes
        UIButtonEye                matlab.ui.control.Button
        UIButtonPhase              matlab.ui.control.Button
        UISpinnerCell              matlab.ui.control.Spinner
        LabelEye                   matlab.ui.control.Label
        LabelPhase                 matlab.ui.control.Label
        LabelCell                  matlab.ui.control.Label
        LabelRfParams              matlab.ui.control.Label
    end


    % Values to share across functions
    properties (Access = private)
        Eye
        Phase
        Cell
        Parameters
        CellPop
    end
    
    % Custom functions
    methods (Access = private)
        
        % Update plot
        function updateplot(app)
            
            % Make receptive field images
            [rfL, rfR] = BEM_filter(app.Parameters, app.CellPop, app.Cell, app.Phase);
            
            % Select the desired eye
            switch lower(app.Eye)
                case 'left'
                    Im = rfL;
                case 'right'
                    Im = rfR;
            end
            
            % Display
            imshow(Im, 'DisplayRange', [-1, 1], 'Parent', app.UIPlot);
        end
        
        % Update text
        function updatetext(app)
            
            % Cell index
            C = app.Cell;
            
            % Create string
            TextString = {};
            TextString{end+1} = ['Cell #' num2str(C)];
            TextString{end+1} = '';
            TextString{end+1} = ['X = ' num2str(app.CellPop.X(C), '%0.2f') '°'];
            TextString{end+1} = ['Y = ' num2str(app.CellPop.Y(C), '%0.2f') '°'];
            TextString{end+1} = ['Sigma1 = ' num2str(app.CellPop.Sigma1(C), '%0.2f') '°'];
            TextString{end+1} = ['Sigma2 = ' num2str(app.CellPop.Sigma2(C), '%0.2f') '°'];
            TextString{end+1} = ['SF = ' num2str(app.CellPop.SF(C), '%0.2f') ' cpd'];
            TextString{end+1} = ['TuningPos = ' num2str(app.CellPop.TuningPos(C), '%0.2f') '°'];
            TextString{end+1} = ['TuningPha = ' num2str(app.CellPop.TuningPha(C), '%0.2f') '°'];
            TextString{end+1} = ['Orientation = ' num2str(app.CellPop.Ori(C), '%0.2f') ' rad'];
            
            % Display
            app.LabelRfParams.Text = TextString;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Parameters, CellPop)
            
            % Help message
            if nargin < 3
                error('Usage:: BEM_plot_receptivefield(Parameters, CellPop)')
            end
            
            % Parse inputs
            app.Parameters = Parameters;
            app.CellPop = CellPop;
            
            % Default start pulled from GUI
            app.Eye = app.UIButtonEye.Text;
            app.Phase = app.UIButtonPhase.Text;
            app.Cell = app.UISpinnerCell.Value;
            
            % Limit cell counter display based on how many cells are present
            app.UISpinnerCell.Limits = [0, Parameters.NumCells];
            
            % Update plot
            updateplot(app);
            
            % Update text
            updatetext(app);
            
        end

        % Button pushed function: UIButtonEye
        function ChangeEye(app, event)
            
            % Update display value based on current value
            switch app.UIButtonEye.Text
                case 'Left'
                    app.Eye = 'Right';
                    app.UIButtonEye.Text = 'Right';
                case 'Right'
                    app.Eye = 'Left';
                    app.UIButtonEye.Text = 'Left';
            end
            
            % Update plot
            updateplot(app);
            
        end

        % Button pushed function: UIButtonPhase
        function ChangePhase(app, event)
            
            % Update display value based on current value
            switch app.UIButtonPhase.Text
                case '0'
                    app.UIButtonPhase.Text = 'π';
                    app.Phase = pi;
                case '0.5 π'
                    app.UIButtonPhase.Text = '1.5 π';
                    app.Phase = 1.5 * pi;
                case 'π'
                    app.UIButtonPhase.Text = '0.5 π';
                    app.Phase = 0.5 * pi;
                case '1.5 π'
                    app.UIButtonPhase.Text = '0';
                    app.Phase = 0;
            end
            
            % Update plot
            updateplot(app);
            
        end

        % Value changed function: UISpinnerCell
        function ChangeCell(app, event)
            
            % Pull value from GUI
            app.Cell = app.UISpinnerCell.Value;
            
            % Special case, don't allow zero
            if app.Cell == 0
                app.UISpinnerCell.Value = 1;
                app.Cell = 1;
            end
            
            % Update plot
            updateplot(app);
            
            % Update text
            updatetext(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BEMreceptivefieldUIFigure = uifigure;
            app.BEMreceptivefieldUIFigure.Name = 'BEM receptive field';
            app.UIPlot = uiaxes(app.BEMreceptivefieldUIFigure);
            app.UIPlot.XTick = [];
            app.UIPlot.YTick = [];
            app.UIButtonEye = uibutton(app.BEMreceptivefieldUIFigure, 'push');
            app.UIButtonEye.ButtonPushedFcn = createCallbackFcn(app, @ChangeEye, true);
            app.UIButtonEye.Text = 'Left';
            app.UIButtonPhase = uibutton(app.BEMreceptivefieldUIFigure, 'push');
            app.UIButtonPhase.ButtonPushedFcn = createCallbackFcn(app, @ChangePhase, true);
            app.UIButtonPhase.Text = '0';
            app.UISpinnerCell = uispinner(app.BEMreceptivefieldUIFigure);
            app.UISpinnerCell.Limits = [0 Inf];
            app.UISpinnerCell.ValueChangedFcn = createCallbackFcn(app, @ChangeCell, true);
            app.UISpinnerCell.Value = 1;
            app.LabelEye = uilabel(app.BEMreceptivefieldUIFigure);
            app.LabelEye.Text = 'Eye';
            app.LabelPhase = uilabel(app.BEMreceptivefieldUIFigure);
            app.LabelPhase.Text = 'Phase';
            app.LabelCell = uilabel(app.BEMreceptivefieldUIFigure);
            app.LabelCell.Text = 'Cell';
            app.LabelRfParams = uilabel(app.BEMreceptivefieldUIFigure);
            app.LabelRfParams.Text = {'Cell #1'; ''; 'X = 0'; 'Y = 0'; 'Sigma1= 0'; 'Sigma2 = 0'; 'SF = 0'; 'TuningPos = 0'; 'TuningPha = 0'; 'Ori = 0'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = BEM_plot_receptivefield(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BEMreceptivefieldUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BEMreceptivefieldUIFigure)
        end
    end
end