classdef BEM_plot_stimulus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BEMstimulusUIFigure  matlab.ui.Figure
        UIPlot               matlab.ui.control.UIAxes
        UIButtonEye          matlab.ui.control.Button
        UISpinnerDisparity   matlab.ui.control.Spinner
        UISpinnerAperture    matlab.ui.control.Spinner
        UISpinnerFrame       matlab.ui.control.Spinner
        UISwitchAnaglyph     matlab.ui.control.Switch
        LabelEye             matlab.ui.control.Label
        LabelDisparity       matlab.ui.control.Label
        LabelAperture        matlab.ui.control.Label
        LabelFrame           matlab.ui.control.Label
        LabelAnaglyph        matlab.ui.control.Label
    end

    
    % Values to share across functions
    properties (Access = private)
        Eye
        Disparity
        Aperture
        Frame
        Anaglyph
        Parameters
        Stimulus
    end
    
    % Custom functions
    methods (Access = private)
        
        % Update plot
        function updateplot(app)
            
            % Indices
            [~, D] = min(abs(app.Parameters.Stim.Disparity - app.Disparity));
            A = app.Aperture;
            F = app.Frame;
            E = app.Eye;
            
            % Pull images
            Im(:,:,1) = app.Stimulus.ImL(:, :, D, A, F);
            Im(:,:,2) = app.Stimulus.ImR(:, :, D, A, F);
            
            % Anaglyph mode
            if app.Anaglyph
                imshowpair(Im(:,:,1), Im(:,:,2), 'falsecolor', 'ColorChannels', [1 2 2], 'Parent', app.UIPlot);
            end
            
            % No anaglyph mode
            if ~app.Anaglyph
                imshow(Im(:,:,E), 'DisplayRange', [-1 1], 'Parent', app.UIPlot);
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Parameters, Stimulus)
            
            % Help message
            if nargin < 3
                error('Usage:: BEM_plot_stimulus(Parameters, Stimulus)')
            end
            
            % Parse inputs
            app.Parameters = Parameters;
            app.Stimulus = Stimulus;
            
            % Default disparity from Params
            app.Disparity = app.Parameters.Stim.Disparity(1);
            
            % Hard-coded default start values
            app.Eye = 1;
            app.Aperture = 1;
            app.Frame = 1;
            app.Anaglyph = 0;
            
            % Limit disparity counter
            app.UISpinnerDisparity.Value = app.Disparity;
            if length(Parameters.Stim.Disparity) > 1
                app.UISpinnerDisparity.Limits = [min(Parameters.Stim.Disparity), max(Parameters.Stim.Disparity)];
                app.UISpinnerDisparity.Step = diff(Parameters.Stim.Disparity(1:2));
            else
                app.UISpinnerDisparity.Limits = [-Inf, Parameters.Stim.Disparity];
                app.UISpinnerDisparity.Step = 1;
            end
            
            % Limit aperture & frame counters
            app.UISpinnerAperture.Limits = [0, Parameters.Stim.ApSteps];
            app.UISpinnerFrame.Limits = [0, Parameters.Stim.Nframes];
            
            % Update plot
            updateplot(app);
            
        end

        % Button pushed function: UIButtonEye
        function ChangeEye(app, event)
            
            % Update display value based on current value
            switch app.UIButtonEye.Text
                case 'Left'
                    app.Eye = 2;
                    app.UIButtonEye.Text = 'Right';
                case 'Right'
                    app.Eye = 1;
                    app.UIButtonEye.Text = 'Left';
            end
            
            % Update plot
            updateplot(app);
            
        end

        % Value changed function: UISpinnerDisparity
        function ChangeDisparity(app, event)
            
            % Pull value from GUI
            app.Disparity = app.UISpinnerDisparity.Value;
            
            % Update plot
            updateplot(app);
            
        end

        % Value changed function: UISpinnerAperture
        function ChangeAperture(app, event)
            
            % Pull value from GUI
            app.Aperture = app.UISpinnerAperture.Value;
            
            % Special case, don't allow zero
            if app.Aperture == 0
                app.UISpinnerAperture.Value = 1;
                app.Aperture = 1;
            end
            
            % Update plot
            updateplot(app);
            
        end

        % Value changed function: UISpinnerFrame
        function ChangeFrame(app, event)
            
            % Pull value from GUI
            app.Frame = app.UISpinnerFrame.Value;
            
            % Special case, don't allow zero
            if app.Frame == 0
                app.UISpinnerFrame.Value = 1;
                app.Frame = 1;
            end
            
            % Update plot
            updateplot(app);
            
        end

        % Value changed function: UISwitchAnaglyph
        function ChangeAnaglyph(app, event)
            
            % Pull value from GUI
            switch lower(app.UISwitchAnaglyph.Value)
                case 'on'
                    app.Anaglyph = 1;
                case 'off'
                    app.Anaglyph = 0;
            end
            
            % Update plot
            updateplot(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BEMstimulusUIFigure = uifigure;
            app.BEMstimulusUIFigure.Name = 'BEM stimulus';
            app.UIPlot = uiaxes(app.BEMstimulusUIFigure);
            app.UIPlot.XTick = [];
            app.UIPlot.YTick = [];
            app.UIButtonEye = uibutton(app.BEMstimulusUIFigure, 'push');
            app.UIButtonEye.ButtonPushedFcn = createCallbackFcn(app, @ChangeEye, true);
            app.UIButtonEye.Text = 'Left';
            app.UISpinnerDisparity = uispinner(app.BEMstimulusUIFigure);
            app.UISpinnerDisparity.ValueChangedFcn = createCallbackFcn(app, @ChangeDisparity, true);
            app.UISpinnerAperture = uispinner(app.BEMstimulusUIFigure);
            app.UISpinnerAperture.ValueChangedFcn = createCallbackFcn(app, @ChangeAperture, true);
            app.UISpinnerAperture.Value = 1;
            app.UISpinnerFrame = uispinner(app.BEMstimulusUIFigure);
            app.UISpinnerFrame.ValueChangedFcn = createCallbackFcn(app, @ChangeFrame, true);
            app.UISpinnerFrame.Value = 1;
            app.UISwitchAnaglyph = uiswitch(app.BEMstimulusUIFigure, 'slider');
            app.UISwitchAnaglyph.ValueChangedFcn = createCallbackFcn(app, @ChangeAnaglyph, true);
            app.LabelEye = uilabel(app.BEMstimulusUIFigure);
            app.LabelEye.Text = 'Eye';
            app.LabelDisparity = uilabel(app.BEMstimulusUIFigure);
            app.LabelDisparity.Text = 'Disparity';
            app.LabelAperture = uilabel(app.BEMstimulusUIFigure);
            app.LabelAperture.Text = 'Aperture';
            app.LabelFrame = uilabel(app.BEMstimulusUIFigure);
            app.LabelFrame.Text = 'Frame';
            app.LabelAnaglyph = uilabel(app.BEMstimulusUIFigure);
            app.LabelAnaglyph.Text = 'Anaglyph';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BEM_plot_stimulus(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BEMstimulusUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BEMstimulusUIFigure)
        end
    end
end