classdef BEM_plot_tuningcurve < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BEMtuningcurveUIFigure    matlab.ui.Figure
        UIPlotTuningCurve         matlab.ui.control.UIAxes
        UIPlotTimecourse          matlab.ui.control.UIAxes
        LabelTuningCurve          matlab.ui.control.Label
        LabelTimecourse           matlab.ui.control.Label
        TickPanel                 matlab.ui.container.Panel
        UITickOutputType          matlab.ui.control.CheckBox
        UITickAverageCells        matlab.ui.control.CheckBox
        UITickAverageDisparities  matlab.ui.control.CheckBox
        UITickAverageApertures    matlab.ui.control.CheckBox
        UITickAverageFrames       matlab.ui.control.CheckBox
        UISpinnerCell             matlab.ui.control.Spinner
        UISpinnerDisparity        matlab.ui.control.Spinner
        UISpinnerAperture         matlab.ui.control.Spinner
        UISpinnerFrame            matlab.ui.control.Spinner
        CellLabel                 matlab.ui.control.Label
        DisparityLabel            matlab.ui.control.Label
        ApertureLabel             matlab.ui.control.Label
        FrameLabel                matlab.ui.control.Label
        UISpinnerScale            matlab.ui.control.Spinner
        ScaleLabel                matlab.ui.control.Label
    end

    
    % Values to share across functions
    properties (Access = private)
        Display
        Parameters
        CellPop
        Simple
        Complex
    end
    
    % Custom functions
    methods (Access = private)
        
        %%%%%%%%%%%%%%%%%%%%%%%%%
        %% Generic update plot %%
        %%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Generic update plot
        function updateplot(app)
            
            % Update both plot areas
            updatetuningcurveplot(app);
            updatetimecourseplot(app);
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% Update tuning curve plot %%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function updatetuningcurveplot(app)
            
            % Indices
            C = app.Display.Cell; % Index
            D = app.Display.Disparity; % Index
            A = app.Display.Aperture; % Index
            F = app.Display.Frame; % Index
            O = app.Display.OutputType; % String
            
            % Get data
            X = app.Parameters.Stim.Disparity;
            switch O
                case 'simple'
                    Y = app.Simple;
                case 'complex'
                    Y = app.Complex;
            end
            
            % Average across cells
            if app.Display.AverageCells
                Y = mean(Y, 1);
            else
                Y = Y(C, :, :, :, :);
            end
            
            % Average across apertures
            if app.Display.AverageApertures
                Y = mean(Y, 3);
            else
                Y = Y(:, :, A, :, :);
            end
            
            % Average across frames
            if app.Display.AverageFrames
                E = std(Y, [], 4) ./ sqrt(size(Y, 4));
                Y = mean(Y, 4);
            else
                Y = Y(:, :, :, F, :);
                E = nan(size(Y));
            end
            
            % Reshape into;
            %  Simple  [disparity x phase]
            %  Complex [disparity x 1]
            Y = squeeze(Y);
            E = squeeze(E);
            
            % Plot
            switch O
                case 'simple'
                    
                    % Plot
                    h = plot(app.UIPlotTuningCurve, X, Y);
                    
                    % Tidy
                    h(1).Color = [1 0.2 0.2];
                    h(2).Color = [1 0.6 0.6];
                    h(3).Color = [0.2 0.2 1];
                    h(4).Color = [0.6 0.6 1];
                    h(1).LineStyle = '-';
                    h(2).LineStyle = ':';
                    h(3).LineStyle = '-';
                    h(4).LineStyle = ':';
                    h(1).LineWidth = 2;
                    h(2).LineWidth = 4;
                    h(3).LineWidth = 2;
                    h(4).LineWidth = 4;
                    
                case 'complex'
                    
                    % Plot
                    h = errorbar(app.UIPlotTuningCurve, X, Y, E);
                    
                    % Tidy
                    h.Color = [0.2 0.2 0.2];
                    h.LineStyle = '-';
                    h.LineWidth = 4;
            end
            
            % Scale axis
            app.UIPlotTuningCurve.YLim = [0, app.Display.Scale];
            app.UIPlotTuningCurve.YTick = [0, app.Display.Scale];
            if length(X) > 1
                app.UIPlotTuningCurve.XTick = round(linspace(min(X), max(X), 11), 2);
            end   
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% Update timecourse plot %%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function updatetimecourseplot(app)
            
            % Indices
            C = app.Display.Cell; % Index
            D = app.Display.Disparity; % Index
            A = app.Display.Aperture; % Index
            F = app.Display.Frame; % Index
            O = app.Display.OutputType; % String
            
            % Get data
            X = 1 : app.Parameters.Stim.ApSteps;
            switch O
                case 'simple'
                    Y = app.Simple;
                case 'complex'
                    Y = app.Complex;
            end
            
            % Average across cells
            if app.Display.AverageCells
                Y = mean(Y, 1);
            else
                Y = Y(C, :, :, :, :);
            end
            
            % Average across disparities
            if app.Display.AverageDisparities
                Y = mean(Y, 2);
            else
                Y = Y(:, D, :, :, :);
            end
            
            % Average across frames
            if app.Display.AverageFrames
                E = std(Y, [], 4) ./ sqrt(size(Y, 4));
                Y = mean(Y, 4);
            else
                Y = Y(:, :, :, F, :);
                E = nan(size(Y));
            end
            
            % Reshape into;
            %  Simple  [aperture x phase]
            %  Complex [aperture x 1]
            Y = squeeze(Y);
            E = squeeze(E);
            
            % Plot
            switch O
                case 'simple'
                    
                    % Plot
                    h = plot(app.UIPlotTimecourse, X, Y);
                    
                    % Tidy
                    h(1).Color = [1 0.2 0.2];
                    h(2).Color = [1 0.6 0.6];
                    h(3).Color = [0.2 0.2 1];
                    h(4).Color = [0.6 0.6 1];
                    h(1).LineStyle = '-';
                    h(2).LineStyle = ':';
                    h(3).LineStyle = '-';
                    h(4).LineStyle = ':';
                    h(1).LineWidth = 2;
                    h(2).LineWidth = 4;
                    h(3).LineWidth = 2;
                    h(4).LineWidth = 4;
                    
                case 'complex'
                    
                    % Plot
                    h = errorbar(app.UIPlotTimecourse, X, Y, E);
                    
                    % Tidy
                    h.Color = [0.2 0.2 0.2];
                    h.LineStyle = '-';
                    h.LineWidth = 4;
            end
            
            % Scale axis
            app.UIPlotTimecourse.YLim = [0, app.Display.Scale];
            app.UIPlotTimecourse.YTick = [0, app.Display.Scale];
            app.UIPlotTimecourse.XLim = [0, app.Parameters.Stim.ApSteps + 1];
            app.UIPlotTimecourse.XTick = 0 : ceil(log10(app.Parameters.Stim.ApSteps)) : app.Parameters.Stim.ApSteps;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Parameters, CellPop, Simple, Complex)
            
            % Help message
            if nargin < 4
                error('Usage:: BEM_plot_tuningcurve(Parameters, CellPop, Simple, Complex)')
            end
            
            % Parse inputs
            app.Parameters = Parameters;
            app.CellPop = CellPop;
            app.Simple = Simple;
            app.Complex = Complex;
            
            % Default start values pulled from GUI
            app.Display.Cell = app.UISpinnerCell.Value;
            app.Display.Aperture = app.UISpinnerAperture.Value;
            app.Display.Frame = app.UISpinnerFrame.Value;
            app.Display.Scale = 10 .^ app.UISpinnerScale.Value;
            
            % Limit counters
            app.UISpinnerCell.Limits = [0, Parameters.NumCells];
            app.UISpinnerAperture.Limits = [0, Parameters.Stim.ApSteps];
            app.UISpinnerFrame.Limits = [0, Parameters.Stim.Nframes];
            
            % Special case: Disparity
            app.Display.Disparity = 1; % Index
            app.UISpinnerDisparity.Value = Parameters.Stim.Disparity(app.Display.Disparity);
            if length(Parameters.Stim.Disparity) > 1
                app.UISpinnerDisparity.Limits = [min(Parameters.Stim.Disparity), max(Parameters.Stim.Disparity)];
                app.UISpinnerDisparity.Step = diff(Parameters.Stim.Disparity(1:2));
            else
                app.UISpinnerDisparity.Limits = [-Inf, Parameters.Stim.Disparity];
                app.UISpinnerDisparity.Step = 1;
            end
            
            % Default tick boxes values from GUI
            app.Display.OutputType = 'simple';
            app.Display.AverageCells = app.UITickAverageCells.Value;
            app.Display.AverageDisparities = app.UITickAverageDisparities.Value;
            app.Display.AverageApertures = app.UITickAverageApertures.Value;
            app.Display.AverageFrames = app.UITickAverageFrames.Value;
            
            % Limit plot area, X-axis
            app.UIPlotTuningCurve.XLim = app.UISpinnerDisparity.Limits;
            app.UIPlotTimecourse.XLim = [1, Parameters.Stim.ApSteps];
            
            % Limit plot area, Y-axis
            M = max(Complex(:)); % Maximum response
            app.UIPlotTuningCurve.YLim = [0, M];
            app.UIPlotTimecourse.YLim = [0, M];
            
            % Update plots
            updateplot(app);
            
        end

        % Value changed function: UISpinnerCell
        function ChangeCell(app, event)

% Pull value from GUI
app.Display.Cell = app.UISpinnerCell.Value;

% Special case, don't allow zero
if app.Display.Cell == 0
    app.UISpinnerCell.Value = 1;
    app.Display.Cell = 1;
end

% Update plot
updateplot(app);

        end

        % Value changed function: UISpinnerDisparity
        function ChangeDisparity(app, event)

% Find nearest match to GUI value
[~, app.Display.Disparity] = min(abs(app.Parameters.Stim.Disparity - app.UISpinnerDisparity.Value));

% Update plot
updateplot(app);

        end

        % Value changed function: UISpinnerAperture
        function ChangeAperture(app, event)

% Pull value from GUI
app.Display.Aperture = app.UISpinnerAperture.Value;

% Special case, don't allow zero
if app.Display.Aperture == 0
    app.UISpinnerAperture.Value = 1;
    app.Display.Aperture = 1;
end

% Update plot
updateplot(app);

        end

        % Value changed function: UISpinnerFrame
        function ChangeFrame(app, event)

% Pull value from GUI
app.Display.Frame = app.UISpinnerFrame.Value;

% Special case, don't allow zero
if app.Display.Frame == 0
    app.UISpinnerFrame.Value = 1;
    app.Display.Frame = 1;
end

% Update plot
updateplot(app);

        end

        % Value changed function: UITickOutputType
        function ChangeOutputType(app, event)
if app.UITickOutputType.Value
    app.Display.OutputType = 'complex';
else
    app.Display.OutputType = 'simple';
end

updateplot(app);

        end

        % Value changed function: UITickAverageCells
        function ChangeAverageCells(app, event)
app.Display.AverageCells = app.UITickAverageCells.Value;
updateplot(app);

        end

        % Value changed function: UITickAverageDisparities
        function ChangeAverageDisparities(app, event)
app.Display.AverageDisparities = app.UITickAverageDisparities.Value;
updateplot(app);

        end

        % Value changed function: UITickAverageApertures
        function ChangeAveragegApertures(app, event)
app.Display.AverageApertures = app.UITickAverageApertures.Value;
updateplot(app);

        end

        % Value changed function: UITickAverageFrames
        function ChangeAverageFrames(app, event)
app.Display.AverageFrames = app.UITickAverageFrames.Value;
updateplot(app);

        end

        % Value changed function: UISpinnerScale
        function ChangeScale(app, event)
app.Display.Scale = 10 .^ app.UISpinnerScale.Value;
updateplot(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BEMtuningcurveUIFigure = uifigure;
            app.BEMtuningcurveUIFigure.Name = 'BEM tuning curve';
            app.UIPlotTuningCurve = uiaxes(app.BEMtuningcurveUIFigure);
            xlabel(app.UIPlotTuningCurve, 'Disparity (°)')
            ylabel(app.UIPlotTuningCurve, 'Response')
            app.UIPlotTimecourse = uiaxes(app.BEMtuningcurveUIFigure);
            xlabel(app.UIPlotTimecourse, 'Aperture step')
            ylabel(app.UIPlotTimecourse, 'Response')
            app.LabelTuningCurve = uilabel(app.BEMtuningcurveUIFigure);
            app.LabelTuningCurve.Text = 'Disparity tuning curve';
            app.LabelTimecourse = uilabel(app.BEMtuningcurveUIFigure);
            app.LabelTimecourse.Text = 'Timecourse';
            app.TickPanel = uipanel(app.BEMtuningcurveUIFigure);
            app.UITickOutputType = uicheckbox(app.TickPanel);
            app.UITickOutputType.ValueChangedFcn = createCallbackFcn(app, @ChangeOutputType, true);
            app.UITickOutputType.Text = 'Complex cell response';
            app.UITickAverageCells = uicheckbox(app.TickPanel);
            app.UITickAverageCells.ValueChangedFcn = createCallbackFcn(app, @ChangeAverageCells, true);
            app.UITickAverageCells.Text = ' Average cells';
            app.UITickAverageDisparities = uicheckbox(app.TickPanel);
            app.UITickAverageDisparities.ValueChangedFcn = createCallbackFcn(app, @ChangeAverageDisparities, true);
            app.UITickAverageDisparities.Text = ' Average disparities';
            app.UITickAverageApertures = uicheckbox(app.TickPanel);
            app.UITickAverageApertures.ValueChangedFcn = createCallbackFcn(app, @ChangeAveragegApertures, true);
            app.UITickAverageApertures.Text = ' Average apertures';
            app.UITickAverageFrames = uicheckbox(app.TickPanel);
            app.UITickAverageFrames.ValueChangedFcn = createCallbackFcn(app, @ChangeAverageFrames, true);
            app.UITickAverageFrames.Text = ' Average frames';
            app.UISpinnerCell = uispinner(app.BEMtuningcurveUIFigure);
            app.UISpinnerCell.ValueChangedFcn = createCallbackFcn(app, @ChangeCell, true);
            app.UISpinnerCell.Value = 1;
            app.UISpinnerDisparity = uispinner(app.BEMtuningcurveUIFigure);
            app.UISpinnerDisparity.ValueChangedFcn = createCallbackFcn(app, @ChangeDisparity, true);
            app.UISpinnerDisparity.Value = 1;
            app.UISpinnerAperture = uispinner(app.BEMtuningcurveUIFigure);
            app.UISpinnerAperture.ValueChangedFcn = createCallbackFcn(app, @ChangeAperture, true);
            app.UISpinnerAperture.Value = 1;
            app.UISpinnerFrame = uispinner(app.BEMtuningcurveUIFigure);
            app.UISpinnerFrame.ValueChangedFcn = createCallbackFcn(app, @ChangeFrame, true);
            app.UISpinnerFrame.Value = 1;
            app.CellLabel = uilabel(app.BEMtuningcurveUIFigure);
            app.CellLabel.Text = 'Cell';
            app.DisparityLabel = uilabel(app.BEMtuningcurveUIFigure);
            app.DisparityLabel.Text = 'Disparity';
            app.ApertureLabel = uilabel(app.BEMtuningcurveUIFigure);
            app.ApertureLabel.Text = 'Aperture';
            app.FrameLabel = uilabel(app.BEMtuningcurveUIFigure);
            app.FrameLabel.Text = 'Frame';
            app.UISpinnerScale = uispinner(app.BEMtuningcurveUIFigure);
            app.UISpinnerScale.ValueChangedFcn = createCallbackFcn(app, @ChangeScale, true);
            app.UISpinnerScale.Value = 6;
            app.ScaleLabel = uilabel(app.BEMtuningcurveUIFigure);
            app.ScaleLabel.Text = 'Plotting scale';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BEM_plot_tuningcurve(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BEMtuningcurveUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BEMtuningcurveUIFigure)
        end
    end
end